classdef ControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TableOfContentsButtonGroup     matlab.ui.container.ButtonGroup
        MotorButton                    matlab.ui.control.Button
        OscilloscopeButton             matlab.ui.control.Button
        GraphicalResultsButton         matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        ReadMeButton                   matlab.ui.control.Button
        RandomButton                   matlab.ui.control.StateButton
        Button                         matlab.ui.control.StateButton
        EnvironmentControlPannelPanel  matlab.ui.container.Panel
        AccousticNoiseKnobLabel        matlab.ui.control.Label
        AccousticNoiseKnob             matlab.ui.control.DiscreteKnob
        KnobLabel                      matlab.ui.control.Label
        Knob                           matlab.ui.control.Knob
        VoltageGaugeLabel              matlab.ui.control.Label
        VoltageGauge                   matlab.ui.control.Gauge
        Panel                          matlab.ui.container.Panel
        CurrentLimitLampLabel          matlab.ui.control.Label
        CurrentLimitLamp               matlab.ui.control.Lamp
        Lamp2Label                     matlab.ui.control.Label
        Lamp2                          matlab.ui.control.Lamp
        Lamp3Label                     matlab.ui.control.Label
        Lamp3                          matlab.ui.control.Lamp
        ModePanel                      matlab.ui.container.Panel
        GeneratorLampLabel             matlab.ui.control.Label
        GeneratorLamp                  matlab.ui.control.Lamp
        MotorLampLabel                 matlab.ui.control.Label
        MotorLamp                      matlab.ui.control.Lamp
        SettingsPanel                  matlab.ui.container.Panel
        GroundButton                   matlab.ui.control.StateButton
        PowerButton                    matlab.ui.control.StateButton
        PulseWidthModulationPWMPanel   matlab.ui.container.Panel
        ModulationAmplitudeEditFieldLabel  matlab.ui.control.Label
        ModulationAmplitudeEditField   matlab.ui.control.EditField
        ModulationFrequencyLabel       matlab.ui.control.Label
        ModulationFrequencyEditField   matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
    end

%I put the comment here because i cant write it up. I want to set an event
%listener for when a button is clicked from the table of contents, it is
%deactivated while the page is open. If the page closes, it will reactivate
%again. This would allow us to control que amount of pages the user will
%have opened. By doing this, we would not have doubles and probably would
%reduce chances or errors while transmitting the code.
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MotorButton
        function MotorButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.MotorButton.Enable = 'on';        
            % Open the options dialog and pass inputs
            Motor();
            
        end

        % Button pushed function: OscilloscopeButton
        function OscilloscopeButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OscilloscopeButton.Enable = 'off';        
            % Open the options dialog and pass inputs
            oscilloscopeApp();
        end

        % Button pushed function: GraphicalResultsButton
        function GraphicalResultsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.GraphicalResultsButton.Enable = 'off';        
            % Open the options dialog and pass inputs
            GraphicalResults();
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.HelpButton.Enable = 'off';  
            % Open the options dialog and pass inputs
            Help();
        end

        % Button pushed function: ReadMeButton
        function ReadMeButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ReadMeButton.Enable = 'off';  
            % Open the options dialog and pass inputs
            ReadMe();
        end

        % Value changed function: RandomButton
        function RandomButtonValueChanged(app, event)
            %I leave the button  here, can be changed to anything else we
            %might need in the future. 
            value = app.RandomButton.Value;
            
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
           %I leave this knob here so we can play later with values.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TableOfContentsButtonGroup = uibuttongroup(app.UIFigure);
            app.TableOfContentsButtonGroup.Title = 'Table Of Contents';
            app.MotorButton = uibutton(app.TableOfContentsButtonGroup, 'push');
            app.MotorButton.ButtonPushedFcn = createCallbackFcn(app, @MotorButtonPushed, true);
            app.MotorButton.Text = 'Motor';
            app.OscilloscopeButton = uibutton(app.TableOfContentsButtonGroup, 'push');
            app.OscilloscopeButton.ButtonPushedFcn = createCallbackFcn(app, @OscilloscopeButtonPushed, true);
            app.OscilloscopeButton.Text = 'Oscilloscope';
            app.GraphicalResultsButton = uibutton(app.TableOfContentsButtonGroup, 'push');
            app.GraphicalResultsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicalResultsButtonPushed, true);
            app.GraphicalResultsButton.Text = 'Graphical Results';
            app.HelpButton = uibutton(app.TableOfContentsButtonGroup, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ReadMeButton = uibutton(app.TableOfContentsButtonGroup, 'push');
            app.ReadMeButton.ButtonPushedFcn = createCallbackFcn(app, @ReadMeButtonPushed, true);
            app.ReadMeButton.Text = 'Read Me';
            app.RandomButton = uibutton(app.TableOfContentsButtonGroup, 'state');
            app.RandomButton.ValueChangedFcn = createCallbackFcn(app, @RandomButtonValueChanged, true);
            app.RandomButton.Text = 'Random Button';
            app.Button = uibutton(app.TableOfContentsButtonGroup, 'state');
            app.Button.Text = 'Button';
            app.EnvironmentControlPannelPanel = uipanel(app.UIFigure);
            app.EnvironmentControlPannelPanel.Title = 'Environment Control Pannel';
            app.AccousticNoiseKnobLabel = uilabel(app.EnvironmentControlPannelPanel);
            app.AccousticNoiseKnobLabel.Text = 'Accoustic Noise';
            app.AccousticNoiseKnob = uiknob(app.EnvironmentControlPannelPanel, 'discrete');
            app.KnobLabel = uilabel(app.EnvironmentControlPannelPanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.EnvironmentControlPannelPanel, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.VoltageGaugeLabel = uilabel(app.EnvironmentControlPannelPanel);
            app.VoltageGaugeLabel.Text = 'Voltage';
            app.VoltageGauge = uigauge(app.EnvironmentControlPannelPanel, 'circular');
            app.Panel = uipanel(app.EnvironmentControlPannelPanel);
            app.Panel.Title = 'Panel';
            app.CurrentLimitLampLabel = uilabel(app.Panel);
            app.CurrentLimitLampLabel.Text = 'Current Limit';
            app.CurrentLimitLamp = uilamp(app.Panel);
            app.Lamp2Label = uilabel(app.Panel);
            app.Lamp2Label.Text = 'Lamp 2';
            app.Lamp2 = uilamp(app.Panel);
            app.Lamp3Label = uilabel(app.Panel);
            app.Lamp3Label.Text = 'Lamp 3';
            app.Lamp3 = uilamp(app.Panel);
            app.ModePanel = uipanel(app.EnvironmentControlPannelPanel);
            app.ModePanel.Title = 'Mode';
            app.GeneratorLampLabel = uilabel(app.ModePanel);
            app.GeneratorLampLabel.Text = 'Generator';
            app.GeneratorLamp = uilamp(app.ModePanel);
            app.MotorLampLabel = uilabel(app.ModePanel);
            app.MotorLampLabel.Text = ' Motor      ';
            app.MotorLamp = uilamp(app.ModePanel);
            app.SettingsPanel = uipanel(app.EnvironmentControlPannelPanel);
            app.SettingsPanel.Title = 'Settings';
            app.GroundButton = uibutton(app.SettingsPanel, 'state');
            app.GroundButton.Text = 'Ground';
            app.PowerButton = uibutton(app.SettingsPanel, 'state');
            app.PowerButton.Text = 'Power';
            app.PulseWidthModulationPWMPanel = uipanel(app.EnvironmentControlPannelPanel);
            app.PulseWidthModulationPWMPanel.Title = 'Pulse Width Modulation (PWM)';
            app.ModulationAmplitudeEditFieldLabel = uilabel(app.PulseWidthModulationPWMPanel);
            app.ModulationAmplitudeEditFieldLabel.Text = 'Modulation Amplitude';
            app.ModulationAmplitudeEditField = uieditfield(app.PulseWidthModulationPWMPanel, 'text');
            app.ModulationFrequencyLabel = uilabel(app.PulseWidthModulationPWMPanel);
            app.ModulationFrequencyLabel.Text = 'Modulation Frequency';
            app.ModulationFrequencyEditField = uieditfield(app.PulseWidthModulationPWMPanel, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Capture.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlPanel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end