classdef GraphicalResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InductionMotorAnalyisisUIFigure  matlab.ui.Figure
    end

    
    properties (Access = private)
        callingApp % object of the app that calls this activity to exchange info
        fromSimulation
    end

    methods (Access = private)
    
        %function updateplot(app) %Leave them commented, I use them as reference.
            % Get Table UI component data
          %  t = app.UITable.DisplayData;            
            % Plot modified data
           % x2 = t.Age;
            %y2 = t.BloodPressure(:,2);
            %plot(app.StatorCurrents,x2,y2,'-o');
        %end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, simulated)

            app.callingApp = mainApp;
            app.fromSimulation = simulated;
            
            %Creates 3 Subdivisions
            tiledlayout(3,1)

            %First plot
            plot(nexttile,app.fromSimulation.i_dr_output.Time,app.fromSimulation.i_dr_output.Data,app.fromSimulation.i_qr_output.Time,app.fromSimulation.i_qr_output.Data);
            title('Stator Current')
            legend({'stator current/i-qs','stator current/i-ds'})
            xlabel('t(s)')
            ylabel('Amps')
            
            
            plot(nexttile, app.fromSimulation.Torque_output.Time,app.fromSimulation.Torque_output.Data);
            title('Electro-Magnetic Torque')
            xlabel('t(s)')
            ylabel('Nm')
            
            plot(nexttile,app.fromSimulation.i_ds_output.Time,app.fromSimulation.i_ds_output.Data,app.fromSimulation.i_qs_output.Time,app.fromSimulation.i_qs_output.Data);
            title('Rotor Current')
            legend({'rotor current/i-rs','rotor current/i-dr'})
            xlabel('t(s)')
            ylabel('Amps')

        end

        % Callback function
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end

        % Callback function
        function ExitButtonPushed(app, event)
            close(app.InductionMotorAnalyisisUIFigure)
        end

        % Close request function: InductionMotorAnalyisisUIFigure
        function InductionMotorAnalyisisUIFigureCloseRequest(app, event)
            app.callingApp.GraphicalResultsButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InductionMotorAnalyisisUIFigure = uifigure('Visible', 'off');
            app.InductionMotorAnalyisisUIFigure.Name = 'Induction Motor Analyisis';
            app.InductionMotorAnalyisisUIFigure.CloseRequestFcn = createCallbackFcn(app, @InductionMotorAnalyisisUIFigureCloseRequest, true);
            % Show the figure after all components are created
            app.InductionMotorAnalyisisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphicalResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InductionMotorAnalyisisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InductionMotorAnalyisisUIFigure)
        end
    end
end