classdef Motor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        POWERSYSTEMPanel          matlab.ui.container.Panel
        Image                     matlab.ui.control.Image
        Edit_R1                   matlab.ui.control.NumericEditField
        Edit_X2                   matlab.ui.control.NumericEditField
        Edit_R2_S                 matlab.ui.control.NumericEditField
        Edit_R2                   matlab.ui.control.NumericEditField
        Edit_RC                   matlab.ui.control.NumericEditField
        Edit_XM                   matlab.ui.control.NumericEditField
        Edit_X1                   matlab.ui.control.NumericEditField
        PHASEINDUCTIONMOTORLabel  matlab.ui.control.Label
        VoltsLLLabel              matlab.ui.control.Label
        VoltsLLEditField          matlab.ui.control.NumericEditField
        PolesSpinnerLabel         matlab.ui.control.Label
        PolesSpinner              matlab.ui.control.Spinner
        FrequencySliderLabel      matlab.ui.control.Label
        FrequencySlider           matlab.ui.control.Slider
        RPMEditFieldLabel         matlab.ui.control.Label
        RPMEditField              matlab.ui.control.NumericEditField
        SetParametersButton       matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SetParametersButton.Enable = 'off';        
            % Open the options dialog and pass inputs
            %In this section, I want to send the values of the motor to the
            %model so we can Perform different analysis
            %set_param(test,'X1',str2double(app.Edit_X1.Value)); 
            
            % The function "assignin(ws,var,val)" assigns a value
            % "val" to a variable "var" in the workspace "ws"
            % In this case, it creates the variables in the "base"
            % workspace of Matlab and therefore any variable in the same
            % folder (Simulink model will be in the same folder),
            %  will get that value assigned.
            
            assignin("base","Rs",app.Edit_R1.Value);            
            assignin("base","Ls",app.Edit_X1.Value);
            assignin("base","Rr",app.Edit_R2.Value);
            assignin("base","Lr",app.Edit_X2.Value);
            assignin("base","Lm",app.Edit_XM.Value);
            
            %sim("RTinductionmotorJan6updated.slx");
            % Runs the simulation just for the purpose of showing, in
            % display boxes, the parameters recently set.
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.POWERSYSTEMPanel = uipanel(app.UIFigure);
            app.POWERSYSTEMPanel.Title = 'POWER SYSTEM';
            app.Image = uiimage(app.POWERSYSTEMPanel);
            app.Image.ImageSource = '2179-1074-jmoea-16-01-0090-gf01.jpg';
            app.Edit_R1 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_X2 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_R2_S = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_R2 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_RC = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_XM = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.Edit_X1 = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.POWERSYSTEMPanel);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.VoltsLLLabel = uilabel(app.POWERSYSTEMPanel);
            app.VoltsLLLabel.Text = 'Volts (LL)';
            app.VoltsLLEditField = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.PolesSpinnerLabel = uilabel(app.POWERSYSTEMPanel);
            app.PolesSpinnerLabel.Text = 'Poles';
            app.PolesSpinner = uispinner(app.POWERSYSTEMPanel);
            app.FrequencySliderLabel = uilabel(app.POWERSYSTEMPanel);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.POWERSYSTEMPanel);
            app.RPMEditFieldLabel = uilabel(app.POWERSYSTEMPanel);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.POWERSYSTEMPanel, 'numeric');
            app.SetParametersButton = uibutton(app.POWERSYSTEMPanel, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end