classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel_2                      matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        CharacteristicoftheCircuitPanel  matlab.ui.container.Panel
        GridLayout21_3               matlab.ui.container.GridLayout
        PhaseAngledegreesLabel       matlab.ui.control.Label
        ImpedanceAngle               matlab.ui.control.EditField
        ZeqOhmsEditField             matlab.ui.control.EditField
        ZeqOhmsEditFieldLabel        matlab.ui.control.Label
        PFEditField                  matlab.ui.control.EditField
        PFEditFieldLabel             matlab.ui.control.Label
        PowerPanel                   matlab.ui.container.Panel
        GridLayout21_4               matlab.ui.container.GridLayout
        EfficiencyLabel              matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.EditField
        PoutWEditField               matlab.ui.control.EditField
        PoutWEditFieldLabel          matlab.ui.control.Label
        PinWEditFieldLabel           matlab.ui.control.Label
        PinWEditField                matlab.ui.control.EditField
        OtherPanel                   matlab.ui.container.Panel
        GridLayout29                 matlab.ui.container.GridLayout
        SlipEditField                matlab.ui.control.EditField
        SlipEditFieldLabel           matlab.ui.control.Label
        PhaseCurrentALabel           matlab.ui.control.Label
        PhaseCurrentEditField        matlab.ui.control.EditField
        DevelopedTorqueNmEditField   matlab.ui.control.EditField
        DevelopedTorqueNmEditFieldLabel  matlab.ui.control.Label
        PHASEINDUCTIONMOTORLabel     matlab.ui.control.Label
        GridVoltage                  matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        VrmsGridVKnob                matlab.ui.control.Knob
        VrmsEditFieldLabel           matlab.ui.control.Label
        VrmsEditField                matlab.ui.control.NumericEditField
        VoltageLabel                 matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsOhmsEditFieldLabel         matlab.ui.control.Label
        RsOhmsEditField              matlab.ui.control.NumericEditField
        LsHEditFieldLabel            matlab.ui.control.Label
        LsHEditField                 matlab.ui.control.NumericEditField
        LmHEditFieldLabel            matlab.ui.control.Label
        LmHEditField                 matlab.ui.control.NumericEditField
        LrHEditFieldLabel            matlab.ui.control.Label
        LrHEditField                 matlab.ui.control.NumericEditField
        RrOhmsLabel                  matlab.ui.control.Label
        RrOhmsEditField              matlab.ui.control.NumericEditField
        StartButton                  matlab.ui.control.Button
        SetParametersButton          matlab.ui.control.Button
        MotorLoadPanel               matlab.ui.container.Panel
        GridLayout16                 matlab.ui.container.GridLayout
        LoadTorqueLabel              matlab.ui.control.Label
        LoadTorqueKnob               matlab.ui.control.Knob
        TorqueNmLabel                matlab.ui.control.Label
        LoadTorqueEditField          matlab.ui.control.NumericEditField
        DefaultParametersButton      matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        EraseAllButton               matlab.ui.control.Button
        SetParametersMessageBox      matlab.ui.control.EditField
        Panel_3                      matlab.ui.container.Panel
        GridLayout26                 matlab.ui.container.GridLayout
        FuseLampLabel                matlab.ui.control.Label
        FuseLamp                     matlab.ui.control.Lamp
        MotorCharacteristicsPanel    matlab.ui.container.Panel
        GridLayout28                 matlab.ui.container.GridLayout
        FrequencyEditField           matlab.ui.control.NumericEditField
        NumberofPolesSpinnerLabel    matlab.ui.control.Label
        NumberofPolesSpinner         matlab.ui.control.Spinner
        FrequencyHzSliderLabel       matlab.ui.control.Label
        FrequencyHzSlider            matlab.ui.control.Slider
        ResultsTab                   matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        TabGroup2                    matlab.ui.container.TabGroup
        GeneralResultsTab            matlab.ui.container.Tab
        GridLayout23                 matlab.ui.container.GridLayout
        GraphGeneralVoltage          matlab.ui.control.UIAxes
        GraphGeneralCurrent          matlab.ui.control.UIAxes
        GraphGeneralSpeed            matlab.ui.control.UIAxes
        GraphGeneralTorque           matlab.ui.control.UIAxes
        ElectromagneticTorqueTab     matlab.ui.container.Tab
        GridLayout20                 matlab.ui.container.GridLayout
        GraphTabTorque               matlab.ui.control.UIAxes
        SpeedTab                     matlab.ui.container.Tab
        GridLayout25                 matlab.ui.container.GridLayout
        GraphTabSpeed                matlab.ui.control.UIAxes
        PhaseCurrentsStatorTab       matlab.ui.container.Tab
        GridLayout22                 matlab.ui.container.GridLayout
        GraphTabCurrent              matlab.ui.control.UIAxes
        VoltageTab                   matlab.ui.container.Tab
        GridLayout24                 matlab.ui.container.GridLayout
        GraphTabVoltage              matlab.ui.control.UIAxes
        RefreshButton                matlab.ui.control.Button
        TimeStopSliderLabel          matlab.ui.control.Label
        TimeStopSlider               matlab.ui.control.Slider
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout21                 matlab.ui.container.GridLayout
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function plotGraphs(app)
            
            %----General Tab------- GridSource/Motor Model/Rotor speed
            %REFERENCE
            %legend(app.UIAxes_Stator,({'stator current/i-qs','stator current/i-ds'}));
            plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphGeneralVoltage,'on');
            
            plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphGeneralCurrent,'on');
            
            plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphGeneralSpeed,'on');
            
            plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphGeneralTorque,'on');
            %---- Each Specific Tab----
            
            plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphTabTorque,'on');
            
            plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphTabSpeed,'on');
            
            plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphTabVoltage,'on');
            
            plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphTabCurrent,'on');
            
        end
            
        function DisplaySpecificValues(app)%needed to calculare slip whern start button actionned
            freqArray = app.simulated.get('freqS');
            fs = freqArray(1);   
            %frequency=fs;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\GridSource.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            %set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            
            app.FrequencyHzSlider.Value=0;
        end

        % Value changed function: NumberofPolesSpinner
        function NumberofPolesSpinnerValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Value changing function: FrequencyHzSlider
        function FrequencyHzSliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
           
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freqSlider);
            
            app.FrequencyEditField.Value = str2double(freqSlider);
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            valueEdit = app.FrequencyEditField.Value;
           
            freq = num2str(valueEdit);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
            
            app.FrequencyHzSlider.Value = str2double(freq);
            
        end

        % Callback function
        function SpeedrpmEditFieldValueChanged(app, event)
            value = app.SpeedrpmEditField.Value;
            
            mspeed = num2str(value);
            set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value', mspeed);
            
            
        end

        % Value changed function: VrmsGridVKnob
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(round(changingValue,1)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage LL'],'Value', voltKnob);

            app.VrmsEditField.Value =  str2double(voltKnob);
        end

        % Value changed function: VrmsEditField
        function VrmsEditFieldValueChanged(app, event)
            valueEdit = app.VrmsEditField.Value;
            
            Vrms = num2str(valueEdit);
           
            set_param([bdroot,'/GridVoltage LL'],'Value', Vrms);
            
            app.VrmsGridVKnob.Value= str2double(Vrms);
        end

        % Value changed function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value =  str2double(loadKnob);
        end

        % Value changed function: LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
            
            loadTorque = num2str(round(valueEdit));
           
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadTorque);
            
            app.LoadTorqueKnob.Value = str2double(loadTorque);
        end

        % Button pushed function: DefaultParametersButton
        function DefaultParametersButtonPushed(app, event)
            app.RsOhmsEditField.Value=0.6832;
            app.LsHEditField.Value=0.002817; %Changed
            app.LmHEditField.Value=0.114; %Changed
            app.LrHEditField.Value=0.0042; %Changed
            app.RrOhmsEditField.Value=0.7265; %Changed
            app.FrequencyHzSlider.Value=60;
            app.FrequencyEditField.Value = app.FrequencyHzSlider.Value;
            app.NumberofPolesSpinner.Value=2; %Changed
            %app.SpeedrpmEditField.Value=3450;
            app.VrmsGridVKnob.Value=220;
            app.VrmsEditField.Value=220;
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
           
            % Setting the Resistances and Inductances 
                       
            Rs = num2str(app.RsOhmsEditField.Value);
            Ls = num2str(app.LsHEditField.Value);
            Rr = num2str(app.RrOhmsEditField.Value);           
            Lr = num2str(app.LrHEditField.Value);
            Lm = num2str(app.LmHEditField.Value);
            
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            
            % Setting the Poles
            
            polesSpinner = num2str(app.NumberofPolesSpinner.Value);
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
            
            
            % Setting the Frequency
            
            freq = num2str(app.FrequencyEditField.Value);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
           
            
%             % Setting the Speed
%            
%             rpm= num2str(app.SpeedrpmEditField.Value);
%             set_param([bdroot,'/Vbyf with PWM/motor speed'],'Value',rpm);
            
            
            % Setting the Voltage

            Vrms = num2str(round(app.VrmsEditField.Value));
           
            set_param([bdroot,'/GridVoltage LL'],'Value', Vrms);
                        
            
            % Setting the Torque

            loadTorque = num2str(round(app.LoadTorqueEditField.Value));
            
            set_param([bdroot,'/Motor Model/Input Torque'],'Value', loadTorque);


            % Saving the current status of Simulink
            
            save_system('simulation\GridSource.slx');
            
            % Displaying Message after Setting is succesful
            
            app.SetParametersMessageBox.Visible = true;
            
            app.SetParametersMessageBox.Value='Parameters sent to model';
       
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsOhmsEditField.Value=0;
            app.LsHEditField.Value=0;
            app.LmHEditField.Value=0;
            app.LrHEditField.Value=0;
            app.RrOhmsEditField.Value=0;
            app.SpeedrpmEditField.Value=0;
            app.NumberofPolesSpinner.Value=0;
            app.FrequencyHzSlider.Value=0;
            app.FrequencyEditField.Value = app.FrequencyHzSlider.Value;
            app.VrmsGridVKnob.Value=0;
            app.VrmsEditField.Value= 0;
            
            app.LoadTorqueKnob.Value=0;
            app.LoadTorqueEditField.Value = 0;
            app.StartButton.Enable ='on';
            app.FuseLamp.Color='white';
            app.SetParametersMessageBox.Visible=false;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            Help();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.FuseLamp.Color='green';
            %save_system('simulation/GridSource.slx');
            save_system('simulation\GridSource.slx');
            app.simulated = sim("simulation\GridSource.slx");
            
            FuseArray = app.simulated.Fuse_Result(length(app.simulated.Fuse_Result));
               %The simulation result is a 4*5001 matrix. The approch I
               %took after analyzing the matrix is that once the fuse is
               %blown, the values (the actual spot in the matrix will vary)
               %towards the end will always be zero. Thus taking the last
               %position of the array (array(length(array)) will give the
               %data needed, i.e. if its blown or not.
            plotGraphs(app);
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                if(FuseArray==0)
                count=3;
                while (count~=0)
                app.FuseLamp.Color='yellow';%blown fuse
                pause(1)
                app.FuseLamp.Color='white';%blown fuse
                pause(1)
                count=count-1;
                end
                app.FuseLamp.Color='yellow';%blown fuse, final state
                elseif  (FuseArray~=0)
                 app.StartButton.Enable ='on';
                 app.FuseLamp.Color='white';            
                end     
            end
            
           %Motor param calculations
           Rs=app.RsOhmsEditField.Value;
           Xs=app.LsHEditField.Value;
           Xm=app.LmHEditField.Value;
           Xr=app.LmHEditField.Value;
           Rr=app.RrOhmsEditField.Value;
           poles = app.NumberofPolesSpinner.Value;
           
           n_sync = (120*app.FrequencyEditField.Value)/poles;
           n_motor = app.simulated.SpeedValues(end);
           slip =(n_sync-n_motor)/n_sync;
           wsync = n_sync * 2*pi/60 ;
           w_motor = n_motor * 2*pi/60 ;
           
           Vph= app.VrmsEditField.Value /sqrt(3);


           Req1=((Xm^2)*(Rr/slip))/((Xr+Xm)^2+(Rr/slip)^2);
           Xeq1= Xm*(Xr*(Xr+Xm)+(Rr/slip))/((Xr+Xm)^2+(Rr/slip)^2);
           Ztotal=complex(Req1+Rs,Xeq1+Xs);

           Iph = Vph / Ztotal;
           
%            Motor characteristics

           %Req referred to the Stator
%            app.REditField.Value=num2str(round(Req1,4));
%            %Xeq referred to the Stator
%            app.XEditField.Value=num2str(round(Xeq1,4));
           %Slip
           app.SlipEditField.Value=num2str(100*slip);
           
           %Total impedance
           %app.ZeqOhmsEditField.Value=num2str(roundZtotal,4));
           app.ImpedanceAngle.Value=num2str(angle(round(Ztotal,4))*57.2958);%multiplied because the angle is in rads
           app.ZeqOhmsEditField.Value=num2str(abs(Ztotal));
           
           %Phase current
           app.PhaseCurrentEditField.Value=num2str(round(abs(Iph),2));
%            app.PhaseAngle.Value=num2str(angle(round(Iph,4))*57.2958);%multiplied because the angle is in rads
%            app.PhaseCurrentEditField.Value=num2str(abs(Iph)) ; 
           
           %PoweFactor
           pf=round(cos(angle(Ztotal)),4);
           
           app.PFEditField.Value=num2str(pf);
           
           %Values selection from toWorsPace 2D arrays
           %volt=app.simulated.Voltage(length(app.simulated.Voltage));%Takes the last value of the array
           %current=app.simulated.Current(length(app.simulated.Current));%Takes the last value of the array
           %is this current rms?
           %torque=app.simulated.Torque(length(app.simulated.Torque));%Takes the last value of the array
           
           torque = app.simulated.get('Torque');
           torqueOutput = torque(end);   
           
           %Mechanical Torque
           app.DevelopedTorqueNmEditField.Value=num2str(torqueOutput);
           
           %Pin
           %Is here rms for voltage good? What about current?
           
           Pin = pf*Vph*abs(Iph);
           Pout = torqueOutput*w_motor;
           app.PinWEditField.Value = num2str(Pin);
           app.PoutWEditField.Value = num2str(Pout);
           app.EfficiencyEditField.Value = num2str(Pout*100/Pin);
           
           app.SetParametersMessageBox.Visible = 'off';
    
        end

        % Value changed function: ExportDropDown
        function ExportDropDownValueChanged(app, event)
            value = app.ExportDropDown.Value;
            
           switch value
               
               case 'Data (Excel)'
                   
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum')%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.xlsx')
               Table_excel = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current)
               writetable(Table_excel,name,'WriteRowNames',true)       
               
               case 'Data (txt)'
                                      
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               t=now;
               d = datetime(floor(t),'ConvertFrom','datenum');%converts it to date and time. floor only gets the date only
               %file creation
               name=strcat('Grid-Lab-Data-',datestr(d),'.txt');
               Table_txt = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current);
               writetable(Table_txt,name,'WriteRowNames',true);   
                   
           end
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Experiment1Button.Enable = 'on';
            delete(app)
            
        end

        % Callback function
        function StopTimesDropDown_2ValueChanged(app, event)
            value = app.StopTimesDropDown_2.Value;
            assignin('base','TimeStop',app.StopTimesDropDown.Value);
            sim('base','StopTime',num2str(app.StopTimesDropDown.Value));
                        
            %----General Tab------- GridSource/Motor Model/Rotor speed
            %REFERENCE
            %legend(app.UIAxes_Stator,({'stator current/i-qs','stator current/i-ds'}));
%             plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
%             legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
%             grid(app.GraphGeneralVoltage,'on');
%             
%             plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
%             legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
%             grid(app.GraphGeneralCurrent,'on');
%             
%             plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
%             grid(app.GraphGeneralSpeed,'on');
%             
%             plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
%             grid(app.GraphGeneralTorque,'on');
%             %---- Each Specific Tab----
%             
%             plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
%             grid(app.GraphTabTorque,'on');
%             
%             plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
%             grid(app.GraphTabSpeed,'on');
%             
%             plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
%             legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
%             grid(app.GraphTabVoltage,'on');
%             
%             plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
%             legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
%             grid(app.GraphTabCurrent,'on');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            simout=sim('GridSource','StopTime',num2str(app.TimeStopSlider.Value));

            app.RefreshButton.Enable='off'
       
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                 app.RefreshButton.Enable='on'
            end  
            
            plot(app.GraphGeneralVoltage, simout.VoltageInput.Time, simout.VoltageInput.Data)        
            plot(app.GraphGeneralCurrent, simout.CurrentInput.Time, simout.CurrentInput.Data)        
            plot(app.GraphGeneralSpeed, simout.SpeedOutput.Time, simout.SpeedOutput.Data)
            plot(app.GraphGeneralTorque, simout.TorqueOutput.Time,simout.TorqueOutput.Data)
            plot(app.GraphTabTorque, simout.TorqueOutput.Time, simout.TorqueOutput.Data)
            plot(app.GraphTabSpeed, simout.SpeedOutput.Time, simout.SpeedOutput.Data)
            plot(app.GraphTabVoltage, simout.VoltageInput.Time, simout.VoltageInput.Data)
            plot(app.GraphTabCurrent, simout.CurrentInput.Time, simout.CurrentInput.Data)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel_2);
            app.CharacteristicoftheCircuitPanel = uipanel(app.GridLayout7);
            app.CharacteristicoftheCircuitPanel.Title = 'Characteristic of the Circuit';
            app.GridLayout21_3 = uigridlayout(app.CharacteristicoftheCircuitPanel);
            app.PhaseAngledegreesLabel = uilabel(app.GridLayout21_3);
            app.PhaseAngledegreesLabel.Text = 'Phase Angle (degrees)';
            app.ImpedanceAngle = uieditfield(app.GridLayout21_3, 'text');
            app.ZeqOhmsEditField = uieditfield(app.GridLayout21_3, 'text');
            app.ZeqOhmsEditFieldLabel = uilabel(app.GridLayout21_3);
            app.ZeqOhmsEditFieldLabel.Text = 'Zeq (Ohms)';
            app.PFEditField = uieditfield(app.GridLayout21_3, 'text');
            app.PFEditFieldLabel = uilabel(app.GridLayout21_3);
            app.PFEditFieldLabel.Text = 'PF';
            app.PowerPanel = uipanel(app.GridLayout7);
            app.PowerPanel.Title = 'Power';
            app.GridLayout21_4 = uigridlayout(app.PowerPanel);
            app.EfficiencyLabel = uilabel(app.GridLayout21_4);
            app.EfficiencyLabel.Text = 'Efficiency (%)';
            app.EfficiencyEditField = uieditfield(app.GridLayout21_4, 'text');
            app.PoutWEditField = uieditfield(app.GridLayout21_4, 'text');
            app.PoutWEditFieldLabel = uilabel(app.GridLayout21_4);
            app.PoutWEditFieldLabel.Text = 'Pout (W)';
            app.PinWEditFieldLabel = uilabel(app.GridLayout21_4);
            app.PinWEditFieldLabel.Text = 'Pin (W)';
            app.PinWEditField = uieditfield(app.GridLayout21_4, 'text');
            app.OtherPanel = uipanel(app.GridLayout7);
            app.OtherPanel.Title = 'Other';
            app.GridLayout29 = uigridlayout(app.OtherPanel);
            app.SlipEditField = uieditfield(app.GridLayout29, 'text');
            app.SlipEditFieldLabel = uilabel(app.GridLayout29);
            app.SlipEditFieldLabel.Text = 'Slip (%)';
            app.PhaseCurrentALabel = uilabel(app.GridLayout29);
            app.PhaseCurrentALabel.Text = 'Phase Current (A)';
            app.PhaseCurrentEditField = uieditfield(app.GridLayout29, 'text');
            app.DevelopedTorqueNmEditField = uieditfield(app.GridLayout29, 'text');
            app.DevelopedTorqueNmEditFieldLabel = uilabel(app.GridLayout29);
            app.DevelopedTorqueNmEditFieldLabel.Text = 'Developed Torque (N*m)';
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.GridVoltage = uipanel(app.GridLayout5);
            app.GridVoltage.Title = 'Grid 3-Phase Voltage';
            app.GridLayout10 = uigridlayout(app.GridVoltage);
            app.VrmsGridVKnob = uiknob(app.GridLayout10, 'continuous');
            app.VrmsGridVKnob.Limits = [0 240];
            app.VrmsGridVKnob.ValueChangedFcn = createCallbackFcn(app, @VrmsGridValueChanging, true);
            app.VrmsGridVKnob.Tag = 'Volts';
            app.VrmsEditFieldLabel = uilabel(app.GridLayout10);
            app.VrmsEditFieldLabel.Text = 'Vrms';
            app.VrmsEditField = uieditfield(app.GridLayout10, 'numeric');
            app.VrmsEditField.ValueChangedFcn = createCallbackFcn(app, @VrmsEditFieldValueChanged, true);
            app.VoltageLabel = uilabel(app.GridLayout10);
            app.VoltageLabel.Text = 'Voltage';
            app.ParametersPanel = uipanel(app.GridLayout5);
            app.ParametersPanel.Title = 'Parameters ';
            app.GridLayout6 = uigridlayout(app.ParametersPanel);
            app.RsOhmsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsOhmsEditFieldLabel.Text = 'Rs (Ohms)';
            app.RsOhmsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LsHEditFieldLabel = uilabel(app.GridLayout6);
            app.LsHEditFieldLabel.Text = 'Ls (H)';
            app.LsHEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LmHEditFieldLabel = uilabel(app.GridLayout6);
            app.LmHEditFieldLabel.Text = 'Lm (H)';
            app.LmHEditField = uieditfield(app.GridLayout6, 'numeric');
            app.LrHEditFieldLabel = uilabel(app.GridLayout6);
            app.LrHEditFieldLabel.Text = 'Lr (H)';
            app.LrHEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrOhmsLabel = uilabel(app.GridLayout6);
            app.RrOhmsLabel.Text = 'Rr (Ohms)';
            app.RrOhmsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.MotorLoadPanel = uipanel(app.GridLayout5);
            app.MotorLoadPanel.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel);
            app.LoadTorqueLabel = uilabel(app.GridLayout16);
            app.LoadTorqueLabel.Text = 'Load Torque';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.Limits = [0 25];
            app.LoadTorqueKnob.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.TorqueNmLabel = uilabel(app.GridLayout16);
            app.TorqueNmLabel.Text = 'Torque (N*m)';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'numeric');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.DefaultParametersButton = uibutton(app.GridLayout5, 'push');
            app.DefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParametersButtonPushed, true);
            app.DefaultParametersButton.Text = 'Default Parameters';
            app.HelpButton = uibutton(app.GridLayout5, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.Panel_3 = uipanel(app.GridLayout5);
            app.GridLayout26 = uigridlayout(app.Panel_3);
            app.FuseLampLabel = uilabel(app.GridLayout26);
            app.FuseLampLabel.Text = 'Fuse';
            app.FuseLamp = uilamp(app.GridLayout26);
            app.MotorCharacteristicsPanel = uipanel(app.GridLayout5);
            app.MotorCharacteristicsPanel.Title = 'Motor Characteristics';
            app.GridLayout28 = uigridlayout(app.MotorCharacteristicsPanel);
            app.FrequencyEditField = uieditfield(app.GridLayout28, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.NumberofPolesSpinnerLabel = uilabel(app.GridLayout28);
            app.NumberofPolesSpinnerLabel.Text = 'Number of Poles';
            app.NumberofPolesSpinner = uispinner(app.GridLayout28);
            app.NumberofPolesSpinner.Step = 2;
            app.NumberofPolesSpinner.Limits = [2 4];
            app.NumberofPolesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofPolesSpinnerValueChanging, true);
            app.NumberofPolesSpinner.Value = 2;
            app.FrequencyHzSliderLabel = uilabel(app.GridLayout28);
            app.FrequencyHzSliderLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzSlider = uislider(app.GridLayout28);
            app.FrequencyHzSlider.Limits = [0 60];
            app.FrequencyHzSlider.ValueChangingFcn = createCallbackFcn(app, @FrequencyHzSliderValueChanging, true);
            app.FrequencyHzSlider.Value = 38.8517909824734;
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout4 = uigridlayout(app.ResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'-Select-', 'Data (Excel)', 'Data (txt)'};
            app.ExportDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportDropDownValueChanged, true);
            app.ExportDropDown.Value = '-Select-';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout23 = uigridlayout(app.GeneralResultsTab);
            app.GraphGeneralVoltage = uiaxes(app.GridLayout23);
            title(app.GraphGeneralVoltage, 'Voltage')
            xlabel(app.GraphGeneralVoltage, 'T(s)')
            ylabel(app.GraphGeneralVoltage, 'V')
            app.GraphGeneralCurrent = uiaxes(app.GridLayout23);
            title(app.GraphGeneralCurrent, 'Current')
            xlabel(app.GraphGeneralCurrent, 'T(s)')
            ylabel(app.GraphGeneralCurrent, 'A')
            app.GraphGeneralSpeed = uiaxes(app.GridLayout23);
            title(app.GraphGeneralSpeed, 'Speed')
            xlabel(app.GraphGeneralSpeed, 'T(s)')
            ylabel(app.GraphGeneralSpeed, 'rpm')
            app.GraphGeneralTorque = uiaxes(app.GridLayout23);
            title(app.GraphGeneralTorque, 'Torque')
            xlabel(app.GraphGeneralTorque, 'T(s)')
            ylabel(app.GraphGeneralTorque, 'N.m')
            app.ElectromagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectromagneticTorqueTab.Title = 'Electro-magnetic Torque';
            app.GridLayout20 = uigridlayout(app.ElectromagneticTorqueTab);
            app.GraphTabTorque = uiaxes(app.GridLayout20);
            xlabel(app.GraphTabTorque, 'T(s)')
            ylabel(app.GraphTabTorque, 'N.m')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed';
            app.GridLayout25 = uigridlayout(app.SpeedTab);
            app.GraphTabSpeed = uiaxes(app.GridLayout25);
            xlabel(app.GraphTabSpeed, 'T(s)')
            ylabel(app.GraphTabSpeed, 'rpm')
            app.PhaseCurrentsStatorTab = uitab(app.TabGroup2);
            app.PhaseCurrentsStatorTab.Title = 'Phase Currents (Stator)';
            app.GridLayout22 = uigridlayout(app.PhaseCurrentsStatorTab);
            app.GraphTabCurrent = uiaxes(app.GridLayout22);
            xlabel(app.GraphTabCurrent, 'T(s)')
            ylabel(app.GraphTabCurrent, 'A')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout24 = uigridlayout(app.VoltageTab);
            app.GraphTabVoltage = uiaxes(app.GridLayout24);
            xlabel(app.GraphTabVoltage, 'X')
            ylabel(app.GraphTabVoltage, 'Y')
            app.RefreshButton = uibutton(app.GridLayout4, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TimeStopSliderLabel = uilabel(app.GridLayout4);
            app.TimeStopSliderLabel.Text = 'Time Stop';
            app.TimeStopSlider = uislider(app.GridLayout4);
            app.TimeStopSlider.Limits = [0 15];
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout21 = uigridlayout(app.ReadMeTab);
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout21);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout21);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end