classdef MainActivity < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        Menu_2               matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
        Menu3                matlab.ui.container.Menu
        Menu4                matlab.ui.container.Menu
        SourcesPanel         matlab.ui.container.Panel
        FEDBYSwitchLabel     matlab.ui.control.Label
        FEDBYSwitch          matlab.ui.control.Switch
        GridPanel            matlab.ui.container.Panel
        VoltageVKnobLabel    matlab.ui.control.Label
        VoltageVKnob         matlab.ui.control.Knob
        VaLampLabel          matlab.ui.control.Label
        VaLamp               matlab.ui.control.Lamp
        VbLampLabel          matlab.ui.control.Label
        VbLamp               matlab.ui.control.Lamp
        VcLampLabel          matlab.ui.control.Label
        VcLamp               matlab.ui.control.Lamp
        VEditField_2Label    matlab.ui.control.Label
        VEditField_2         matlab.ui.control.NumericEditField
        PWMInverterPanel     matlab.ui.container.Panel
        VaLamp_2Label        matlab.ui.control.Label
        VaLamp_2             matlab.ui.control.Lamp
        VbLamp_2Label        matlab.ui.control.Label
        VbLamp_2             matlab.ui.control.Lamp
        VcLamp_2Label        matlab.ui.control.Label
        VcLamp_2             matlab.ui.control.Lamp
        VoltageVKnob_2Label  matlab.ui.control.Label
        VoltageVKnob_2       matlab.ui.control.Knob
        maEditFieldLabel     matlab.ui.control.Label
        maEditField          matlab.ui.control.NumericEditField
        mfEditFieldLabel     matlab.ui.control.Label
        mfEditField          matlab.ui.control.NumericEditField
        VEditFieldLabel      matlab.ui.control.Label
        VEditField           matlab.ui.control.NumericEditField
        ControlPanel         matlab.ui.container.Panel
        LoadPanel            matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function getModelValues(app)
            vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            app.VEditField.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system('simulation\PWMJan27th.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            
            getModelValues(app);
        end

        % Value changing function: VoltageVKnob_2
        function VoltageVKnob_2ValueChanging(app, event)
            changingValue = event.Value;
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Vdc'],'Value', voltKnob);
            getModelValues(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.UIFigure);
            app.Menu3.Text = 'Menu3';
            app.Menu4 = uimenu(app.UIFigure);
            app.Menu4.Text = 'Menu4';
            app.SourcesPanel = uipanel(app.UIFigure);
            app.SourcesPanel.Tooltip = {''};
            app.SourcesPanel.Title = 'Sources';
            app.SourcesPanel.Tag = 'three states';
            app.FEDBYSwitchLabel = uilabel(app.SourcesPanel);
            app.FEDBYSwitchLabel.Text = 'FED BY';
            app.FEDBYSwitch = uiswitch(app.SourcesPanel, 'slider');
            app.FEDBYSwitch.Items = {'GRID', 'INVERTER'};
            app.FEDBYSwitch.Value = 'GRID';
            app.GridPanel = uipanel(app.SourcesPanel);
            app.GridPanel.Title = 'Grid';
            app.VoltageVKnobLabel = uilabel(app.GridPanel);
            app.VoltageVKnobLabel.Text = 'Voltage [V]';
            app.VoltageVKnob = uiknob(app.GridPanel, 'continuous');
            app.VoltageVKnob.Limits = [0 250];
            app.VaLampLabel = uilabel(app.GridPanel);
            app.VaLampLabel.Text = 'Va';
            app.VaLamp = uilamp(app.GridPanel);
            app.VbLampLabel = uilabel(app.GridPanel);
            app.VbLampLabel.Text = 'Vb';
            app.VbLamp = uilamp(app.GridPanel);
            app.VcLampLabel = uilabel(app.GridPanel);
            app.VcLampLabel.Text = 'Vc';
            app.VcLamp = uilamp(app.GridPanel);
            app.VEditField_2Label = uilabel(app.GridPanel);
            app.VEditField_2Label.Text = '[V]';
            app.VEditField_2 = uieditfield(app.GridPanel, 'numeric');
            app.VEditField_2.Editable = 'off';
            app.VEditField_2.Tooltip = {'Voltage set by the knob'};
            app.PWMInverterPanel = uipanel(app.SourcesPanel);
            app.PWMInverterPanel.Title = 'PWM Inverter';
            app.VaLamp_2Label = uilabel(app.PWMInverterPanel);
            app.VaLamp_2Label.Text = 'Va';
            app.VaLamp_2 = uilamp(app.PWMInverterPanel);
            app.VbLamp_2Label = uilabel(app.PWMInverterPanel);
            app.VbLamp_2Label.Text = 'Vb';
            app.VbLamp_2 = uilamp(app.PWMInverterPanel);
            app.VcLamp_2Label = uilabel(app.PWMInverterPanel);
            app.VcLamp_2Label.Text = 'Vc';
            app.VcLamp_2 = uilamp(app.PWMInverterPanel);
            app.VoltageVKnob_2Label = uilabel(app.PWMInverterPanel);
            app.VoltageVKnob_2Label.Text = 'Voltage [V]';
            app.VoltageVKnob_2 = uiknob(app.PWMInverterPanel, 'continuous');
            app.VoltageVKnob_2.Limits = [0 250];
            app.VoltageVKnob_2.ValueChangingFcn = createCallbackFcn(app, @VoltageVKnob_2ValueChanging, true);
            app.maEditFieldLabel = uilabel(app.PWMInverterPanel);
            app.maEditFieldLabel.Text = 'ma';
            app.maEditField = uieditfield(app.PWMInverterPanel, 'numeric');
            app.mfEditFieldLabel = uilabel(app.PWMInverterPanel);
            app.mfEditFieldLabel.Text = 'mf';
            app.mfEditField = uieditfield(app.PWMInverterPanel, 'numeric');
            app.VEditFieldLabel = uilabel(app.PWMInverterPanel);
            app.VEditFieldLabel.Text = '[V]';
            app.VEditField = uieditfield(app.PWMInverterPanel, 'numeric');
            app.VEditField.Editable = 'off';
            app.VEditField.Tooltip = {'Voltage set by the knob'};
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Tooltip = {''};
            app.ControlPanel.Title = 'Control';
            app.ControlPanel.Tag = 'three states';
            app.LoadPanel = uipanel(app.UIFigure);
            app.LoadPanel.Tooltip = {''};
            app.LoadPanel.Title = 'Load';
            app.LoadPanel.Tag = 'three states';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainActivity

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end