classdef VbyFClosedLoop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VFLaboratoryClosedLoopUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MotorParametersTab              matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        PhaseCurrentPanel_2             matlab.ui.container.Panel
        GridLayout21_3                  matlab.ui.container.GridLayout
        PinLabel                        matlab.ui.control.Label
        PinEditField                    matlab.ui.control.EditField
        AbsoluteEditField_5Label        matlab.ui.control.Label
        PhaseAbsoluteEditField          matlab.ui.control.EditField
        FreqHzLabel                     matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        PHASEINDUCTIONMOTORLabel_2      matlab.ui.control.Label
        SetParametersButton             matlab.ui.control.Button
        MotorSpeedPanel                 matlab.ui.container.Panel
        GridLayout10_2                  matlab.ui.container.GridLayout
        mfIndexLabel                    matlab.ui.control.Label
        mfIndexEditField                matlab.ui.control.NumericEditField
        SpeedrpmEditField               matlab.ui.control.NumericEditField
        RPMLabel                        matlab.ui.control.Label
        SpeedKnob                       matlab.ui.control.Knob
        RPMLabel_2                      matlab.ui.control.Label
        ParametersOhmsPanel             matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        RsEditFieldLabel                matlab.ui.control.Label
        RsEditField                     matlab.ui.control.NumericEditField
        jXsEditFieldLabel               matlab.ui.control.Label
        jXsEditField                    matlab.ui.control.NumericEditField
        jXmEditFieldLabel               matlab.ui.control.Label
        jXmEditField                    matlab.ui.control.NumericEditField
        jXrEditFieldLabel               matlab.ui.control.Label
        jXrEditField                    matlab.ui.control.NumericEditField
        RrEditFieldLabel                matlab.ui.control.Label
        RrEditField                     matlab.ui.control.NumericEditField
        MotorLoadPanel_2                matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        LoadTorqueLabel                 matlab.ui.control.Label
        LoadTorqueKnob                  matlab.ui.control.Knob
        TorqueNmLabel                   matlab.ui.control.Label
        LoadTorqueEditField             matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        DefaultParametersButton         matlab.ui.control.Button
        EraseAllButton                  matlab.ui.control.Button
        SetParametersMessageBox         matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        FuseLampLabel                   matlab.ui.control.Label
        FuseLamp                        matlab.ui.control.Lamp
        TotalImpedancePanel_2           matlab.ui.container.Panel
        GridLayout21_4                  matlab.ui.container.GridLayout
        AbsoluteEditField_6Label        matlab.ui.control.Label
        ImpedanceAbsolute               matlab.ui.control.EditField
        AngleEditField_2Label_2         matlab.ui.control.Label
        ImpedanceAngle                  matlab.ui.control.EditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField                      matlab.ui.control.EditField
        XEditField                      matlab.ui.control.EditField
        XEditField_2Label               matlab.ui.control.Label
        REditField_2Label               matlab.ui.control.Label
        REditField                      matlab.ui.control.EditField
        PerformancePanel_2              matlab.ui.container.Panel
        GridLayout21_8                  matlab.ui.container.GridLayout
        PFEditField_2Label              matlab.ui.control.Label
        PFEditField                     matlab.ui.control.EditField
        EfficiencyEditFieldLabel        matlab.ui.control.Label
        EfficiencyEditField             matlab.ui.control.EditField
        SlipLabel                       matlab.ui.control.Label
        SlipEditField                   matlab.ui.control.EditField
        PoutLabel                       matlab.ui.control.Label
        PoutEditField                   matlab.ui.control.EditField
        TorqueEditFieldLabel            matlab.ui.control.Label
        TorqueEditField                 matlab.ui.control.EditField
        PIDPanel                        matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        PEditFieldLabel                 matlab.ui.control.Label
        PEditField                      matlab.ui.control.EditField
        IEditFieldLabel                 matlab.ui.control.Label
        IEditField                      matlab.ui.control.EditField
        DEditFieldLabel                 matlab.ui.control.Label
        DEditField                      matlab.ui.control.EditField
        ResultsTab                      matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ExportDropDownLabel             matlab.ui.control.Label
        ExportDropDown                  matlab.ui.control.DropDown
        TabGroup2                       matlab.ui.container.TabGroup
        GeneralResultsTab               matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        GraphGeneralVoltage             matlab.ui.control.UIAxes
        GraphGeneralCurrent             matlab.ui.control.UIAxes
        GraphGeneralSpeed               matlab.ui.control.UIAxes
        GraphGeneralTorque              matlab.ui.control.UIAxes
        ElectroMagneticTorqueTab        matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        GraphTabTorque                  matlab.ui.control.UIAxes
        SpeedTab                        matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        GraphTabSpeed                   matlab.ui.control.UIAxes
        Current                         matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        GraphTabCurrent                 matlab.ui.control.UIAxes
        VoltageTab                      matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        GraphTabVoltage                 matlab.ui.control.UIAxes
        RefreshButton                   matlab.ui.control.Button
        TimeStopSliderLabel             matlab.ui.control.Label
        TimeStopSlider                  matlab.ui.control.Slider
        ReadMeTab                       matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea     matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated
    end
    
    
    methods (Access = private)
        
        function plotGraphs(app)
            
            %----General Tab------- ClosedLoopApril1/Motor Model/Rotor speed
            
            plot(app.GraphGeneralVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphGeneralVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphGeneralVoltage,'on');
            
            plot(app.GraphGeneralCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphGeneralCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphGeneralCurrent,'on');
            
            plot(app.GraphGeneralSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphGeneralSpeed,'on');
            
            plot(app.GraphGeneralTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphGeneralTorque,'on');
            %---- Each Specific Tab----
            
            plot(app.GraphTabTorque,app.simulated.TorqueOutput.Time,app.simulated.TorqueOutput.Data)
            grid(app.GraphTabTorque,'on');
            
            plot(app.GraphTabSpeed,app.simulated.SpeedOutput.Time,app.simulated.SpeedOutput.Data)
            grid(app.GraphTabSpeed,'on');
            
            plot(app.GraphTabVoltage,app.simulated.VoltageInput.Time,app.simulated.VoltageInput.Data)
            legend(app.GraphTabVoltage,({'Va','Vb','Vc'}));
            grid(app.GraphTabVoltage,'on');
            
            plot(app.GraphTabCurrent,app.simulated.CurrentInput.Time,app.simulated.CurrentInput.Data)
            legend(app.GraphTabCurrent,({'Ia','Ib','Ic'}));
            grid(app.GraphTabCurrent,'on');
        end
        
        function DisplaySpecificValues(app)
            %app.SynchronousFrequencyEditField.Value = get_param([bdroot,'/Vbyf with PWM/SyncFreq1'], 'Value');
            freqArray = app.simulated.get('freqS');
            fs = freqArray(1);
            
            app.FrequencyEditField.Value = fs;
            %app.FrequencyEditField.FontColor='red';%To makeit more visible to the user then it changes
            
        end


    end
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.VFLaboratoryClosedLoopUIFigure.Resize='off';
            app.callingApp = mainApp;
            movegui(app.VFLaboratoryClosedLoopUIFigure,'center');
            load_system('simulation\ClosedLoopApril1.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/ControlSelect'],'Value',num2str(1)); 
            
        end

        % Callback function
        function NumberofPolesSpinnerValueChanging(app, event)
%             changingValue = event.Value; % reads the value changed in the event happening
%             
%             polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
%             % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
%             
%             set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Callback function
        function FrequencyHzSliderValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            % We choose FreqValueChanging instead of FreqValueChanged
            % because it is more precise (i.e. it reacts to the continuous
            % variation of the slider)
            
            freqSlider = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            % round() avoids gettings values like 45.4168751
                   
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freqSlider);

            app.FrequencyEditField.Value = str2double(freqSlider); 
            
        end

        % Callback function
        function FrequencyEditFieldValueChanged(app, event)
            valueEdit = app.FrequencyEditField.Value;
            
            freq = num2str(valueEdit);
            
            set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);
            
            app.FrequencyHzSlider.Value = str2double(freq);
            
        end

        % Value changing function: SpeedKnob
        function SpeedKnobValueChanging(app, event)
            changingValue = event.Value;
            
            speedKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
      
            set_param([bdroot,'/Target Speed'],'Value', speedKnob);
            
            app.SpeedrpmEditField.Value = str2double(speedKnob);      

        end

        % Value changed function: SpeedrpmEditField
        function SpeedrpmEditFieldValueChanged(app, event)
            value = app.SpeedrpmEditField.Value;
            
            mspeed = num2str(value);
            set_param([bdroot,'/Target Speed'],'Value', mspeed);
            
            app.SpeedKnob.Value = str2double(mspeed);
            
        end

        % Value changed function: mfIndexEditField
        function mfIndexEditFieldValueChanged(app, event)
            valueEdit = app.mfIndexEditField.Value;

            fs = valueEdit;
        
            mwor = get_param(bdroot, 'modelworkspace');
            
            mwor.assignin('vectorMF',[0 1/(2*fs) 1/fs]);
            
%             mf = num2str(valueEdit);
%             set_param([bdroot,'/PWM + inverter/Three-phase PWM/Triangle Generator'],'Value', mf);
            
        end

        % Value changing function: LoadTorqueKnob
        function LoadTorqueKnobValueChanging(app, event)
            changingValue = event.Value;
            
            loadKnob = num2str(round(changingValue)); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Input Torque'],'Value', loadKnob);
           
            app.LoadTorqueEditField.Value = str2double(loadKnob);
        end

        % Value changed function: LoadTorqueEditField
        function LoadTorqueEditFieldValueChanged(app, event)
            valueEdit = app.LoadTorqueEditField.Value;
           
            loadTorque = num2str(round(valueEdit));
            
            set_param([bdroot,'/Input Torque'],'Value', loadTorque);
            
            app.LoadTorqueKnob.Value = str2double(loadTorque);
        end

        % Button pushed function: DefaultParametersButton
        function DefaultParametersButtonPushed(app, event)
            app.RsEditField.Value=0.6832;
            app.jXsEditField.Value=0.002817;
            app.jXmEditField.Value=0.114;
            app.jXrEditField.Value=0.0042;
            app.RrEditField.Value=0.7265;
            %app.FrequencyHzSlider.Value=60;
            %app.FrequencyEditField.Value = app.FrequencyHzSlider.Value;
            %app.NumberofPolesSpinner.Value=2;
            app.mfIndexEditField.Value=5100;
            app.SpeedrpmEditField.Value=3450;
            app.SpeedKnob.Value=3450;
            app.PEditField.Value=num2str(12);
            app.IEditField.Value=num2str(0.001);
            app.DEditField.Value=num2str(0);
            
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed(app, event)
            %app.SetParametersButton.Enable = 'off';        
            
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.jXsEditField.Value);
            Rr = num2str(app.RrEditField.Value);           
            Lr = num2str(app.jXrEditField.Value);
            Lm = num2str(app.jXmEditField.Value);
            
            P = num2str(app.PEditField.Value);
            I = num2str(app.IEditField.Value);
            D = num2str(app.DEditField.Value);
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls); 
            set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            %PID
            set_param([bdroot,'/Vbyf Closed loop/P'],'Value',P);
            set_param([bdroot,'/Vbyf Closed loop/I'],'Value',I);
            set_param([bdroot,'/Vbyf Closed loop/D'],'Value',D);
                                 
            
            % Setting the Poles
            
%             polesSpinner = num2str(app.NumberofPolesSpinner.Value);
%             
%             set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
            
            
            % Setting the Frequency
            
%             freq = num2str(app.FrequencyEditField.Value);
%             
%             set_param([bdroot,'/Grid Source/Frequency'],'Value', freq);


            % Setting the Speed
            
            rpm= num2str(app.SpeedrpmEditField.Value);
            
            set_param([bdroot,'/Target Speed'],'Value',rpm);


            % Setting the Mf
            
            fs = app.mfIndexEditField.Value;
                        
            mwor = get_param(bdroot, 'modelworkspace');
            
            mwor.assignin('vectorMF',[0 1/(2*fs) 1/fs]);


            % Setting the Torque

            loadTorque = num2str(round(app.LoadTorqueEditField.Value));
            
            set_param([bdroot,'/Input Torque'],'Value', loadTorque);


            % Saving the current status of Simulink
            
            save_system('simulation\ClosedLoopApril1.slx');
            
            % Displaying Message after Setting is succesful
           
            app.SetParametersMessageBox.Visible = true;
            
            app.SetParametersMessageBox.Value='Parameters sent to model';
            
        end

        % Button pushed function: EraseAllButton
        function EraseAllButtonPushed(app, event)
            app.RsEditField.Value=0;
            app.jXsEditField.Value=0;
            app.jXmEditField.Value=0;
            app.jXrEditField.Value=0;
            app.RrEditField.Value=0;
            app.mfIndexEditField.Value=0;
            app.SpeedrpmEditField.Value=0;
            app.SpeedKnob.Value=0;
            app.LoadTorqueKnob.Value=0;
            app.FrequencyEditField.Value=0;
            app.FrequencyEditField.FontColor='black';
            
            app.LoadTorqueKnob.Value=0;
            app.LoadTorqueEditField.Value = 0;
            
            %Added on april 4th
            %phase current       
            app.PhaseAbsoluteEditField.Value=num2str(0);

            %Impedance
            app.ZEditField.Value=num2str(0);
            app.ImpedanceAbsolute.Value=num2str(0);
            app.ImpedanceAngle.Value=num2str(0);
            
            %slip
            app.SlipEditField.Value=num2str(0);
            %PF
            app.PFEditField.Value=num2str(0);
            %Efficiency
            app.EfficiencyEditField.Value=num2str(0);
            %X
            app.XEditField.Value=num2str(0);
            %R
            app.REditField.Value=num2str(0);
            %Total mech. torque
            app.TorqueEditField.Value=num2str(0);
            %Pout
            app.PoutEditField.Value=num2str(0);
            %Pin
            app.PinEditField.Value=num2str(0);
            
            %PID
            app.PEditField.Value=num2str(0);
            app.IEditField.Value=num2str(0);
            app.DEditField.Value=num2str(0);
            
            app.SetParametersMessageBox.Visible='off';
            app.SetParametersMessageBox.Visible=false;
            app.FuseLamp.Color='white';
            app.StartButton.Enable='on';
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            Help();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.FuseLamp.Color='green';
            
            save_system('simulation\ClosedLoopApril1.slx');
            app.simulated = sim("simulation\ClosedLoopApril1.slx");
      
            plotGraphs(app);
               FuseArray = app.simulated.Fuse_Result(length(app.simulated.Fuse_Result));
               
               %The simulation result is a 4*5001 matrix. The approch I
               %took after analyzing the matrix is that once the fuse is
               %blown, the values (the actual spot in the matrix will vary)
               %towards the end will always be zero. Thus taking the last
               %position of the array (array(length(array)) will give the
               %data needed, i.e. if its blown or not.
            
            DisplaySpecificValues(app); 
          %  modifica despues, es lo que gab
          %  puso para poner las informaciones sobre el analyzis del graph
          %  en la pantalla
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                if(FuseArray==0)
                count=3;
                while (count~=0)
                app.FuseLamp.Color='yellow';%blown fuse
                pause(1)
                app.FuseLamp.Color='white';%blown fuse
                pause(1)
                count=count-1;
                end
                app.FuseLamp.Color='yellow';%blown fuse, final state

                elseif  (FuseArray~=0)
                 app.StartButton.Enable ='on';
                 app.FuseLamp.Color='white';
                 
                end
            end%end if
            
           %Motor param calculations
           Rs = app.RsEditField.Value;
           Xs = (2*pi*60) * app.jXsEditField.Value;
           Xm = (2*pi*60) * app.jXmEditField.Value;
           Xr = (2*pi*60) * app.jXrEditField.Value;
           Rr= app.RrEditField.Value;
           slip =(2/120)*(3600-app.SpeedrpmEditField.Value)/app.FrequencyEditField.Value;
           %slip = (3600 - app.SpeedrpmEditField.Value)/3600;

           R=((Xm^2)*(Rr/slip))/((Xr+Xm)^2+(Rr/slip)^2);
           X= Xm*(Xr*(Xr+Xm)+(Rr/slip)^2)/((Xr+Xm)^2+(Rr/slip)^2);
           
           
           Zeq=complex(R+Rs,X+Xs);
           Vph=208/sqrt(3);           % Here this is not actually true, we 
                                      % don't know the value of Vph unless we 
                                      % go inside the simulation and check 
                                      % what is the value input for a given speed
           
           
           Is=Vph/Zeq;      % If we retrieve the Correct Value from V/f Control Block
                            % we will get this current and Rotor current
                            % correctly.
           
           % Calculating Irotor and from there Pconv
           
           Ir = abs(complex(R,X))/abs(complex(Rr,Xr));
           
           Pag = 3*(Ir^2)*Rr/slip;
           
           Pconv = Pag * (1-slip);
           
           wm = app.SpeedrpmEditField.Value * ((2*pi)/60);


           %Motor characteristics
           %R
           app.REditField.Value=num2str(round(R,4));
           %X
           app.XEditField.Value=num2str(round(X,4));
           %Slip
           app.SlipEditField.Value=num2str(100*slip);
           
           %Total impedance
           app.ZEditField.Value=num2str(round(Zeq,2));
           app.ImpedanceAngle.Value=num2str(angle(round(Zeq,4))*57.2958);%multiplied because the angle is in rads
           app.ImpedanceAbsolute.Value=num2str(abs(Zeq));
           
           %Phase current
           %app.CurrentEditField.Value=num2str(round(Is,2));
           %app.PhaseAngle.Value=num2str(angle(round(Is,4))*57.2958);%multiplied because the angle is in rads
           app.PhaseAbsoluteEditField.Value=num2str(abs(Is)) ; 
           
           %PoweFactor
           pf=round(cos(angle(Zeq)),4);
           app.PFEditField.Value=num2str(pf);
           
           %Values selection from toWorsPace 2D arrays
           %volt = app.simulated.Voltage(length(app.simulated.Voltage));%Takes the last value of the array
           
           % This voltage might not be necesary if we retrieve the proper
           % value from V/f Control Block
           
           current=app.simulated.Current(length(app.simulated.Current));%Takes the last value of the array
           %is this current rms?
           torque=app.simulated.Torque(length(app.simulated.Torque));%Takes the last value of the array
           
           %Mechanical Torque
           %app.TorqueEditField.Value=num2str(torque);
           
           %Pin
           %Is here rms for voltage good? What about current?
           
           Pin = pf*Vph*current;
           
           app.PinEditField.Value = num2str(Pin);
          
           
           % Pout: When Friction & Windage Losses are Neglected, Pconv =
           % Pout (refer to Power Flow Diagram)
           
           app.PoutEditField.Value = num2str(Pconv);
           
           % Since Tload = Pout/wm and Pout in this case is Pconv then -->
           % --> Tload = Pconv / wm = Tind
           
           Tind = Pconv / wm ;
           app.TorqueEditField.Value = num2str(Tind);
           
           app.SetParametersMessageBox.Visible='off';
        end

        % Value changed function: ExportDropDown
        function ExportDropDownValueChanged(app, event)
            value=app.ExportDropDown.Value;
           switch value
               
               case 'Data (Excel)'
                   
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               d = datetime(floor(now),'ConvertFrom','datenum');%converts it to date and time. floor only gets the date only
               %file creation;
               name=strcat('VF-Lab-Data-',datestr(d),'.xlsx');
               Table_excel = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current);
               writetable(Table_excel,name,'WriteRowNames',true)   ;    
               
               case 'Data (txt)'
                                      
               %torque
               time_torque = app.simulated.TorqueOutput.Time;
               torque=app.simulated.TorqueOutput.Data;
               %speed
               time_speed=app.simulated.SpeedOutput.Time;
               rpm=app.simulated.SpeedOutput.Data;
               %voltage
               time_voltage=app.simulated.VoltageInput.Time;
               voltage=app.simulated.VoltageInput.Data;
               %current
               time_current=app.simulated.CurrentInput.Time;
               current=app.simulated.CurrentInput.Data;
               %date
               d = datetime(floor(now),'ConvertFrom','datenum');%converts it to date and time. floor only gets the date only
               %file creation;
               name=strcat('VF-Lab-Data-',datestr(d),'.txt');
               Table_txt = table(torque,time_torque,time_speed,rpm,time_voltage,voltage,time_current,current);
               writetable(Table_txt,name,'WriteRowNames',true)   ;
                   
           end
            
        end

        % Close request function: VFLaboratoryClosedLoopUIFigure
        function VFLaboratoryClosedLoopUIFigureCloseRequest(app, event)
            app.callingApp.Experiment2Button.Enable = 'on';
            delete(app)
            
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            simout=sim('ClosedLoopApril1','StopTime',num2str(app.TimeStopSlider.Value));

            app.RefreshButton.Enable='off'
       
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                 app.RefreshButton.Enable='on'
            end  
            
            plot(app.GraphGeneralVoltage, simout.VoltageInput.Time, simout.VoltageInput.Data)        
            plot(app.GraphGeneralCurrent, simout.CurrentInput.Time, simout.CurrentInput.Data)        
            plot(app.GraphGeneralSpeed, simout.SpeedOutput.Time, simout.SpeedOutput.Data)
            plot(app.GraphGeneralTorque, simout.TorqueOutput.Time,simout.TorqueOutput.Data)
            plot(app.GraphTabTorque, simout.TorqueOutput.Time, simout.TorqueOutput.Data)
            plot(app.GraphTabSpeed, simout.SpeedOutput.Time, simout.SpeedOutput.Data)
            plot(app.GraphTabVoltage, simout.VoltageInput.Time, simout.VoltageInput.Data)
            plot(app.GraphTabCurrent, simout.CurrentInput.Time, simout.CurrentInput.Data)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VFLaboratoryClosedLoopUIFigure = uifigure('Visible', 'off');
            app.VFLaboratoryClosedLoopUIFigure.Name = 'VbyF Laboratory (Closed Loop)';
            app.VFLaboratoryClosedLoopUIFigure.CloseRequestFcn = createCallbackFcn(app, @VFLaboratoryClosedLoopUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.VFLaboratoryClosedLoopUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel = uipanel(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.Panel);
            app.PhaseCurrentPanel_2 = uipanel(app.GridLayout7);
            app.PhaseCurrentPanel_2.Title = 'Phase Current';
            app.GridLayout21_3 = uigridlayout(app.PhaseCurrentPanel_2);
            app.PinLabel = uilabel(app.GridLayout21_3);
            app.PinLabel.Text = 'Pin';
            app.PinEditField = uieditfield(app.GridLayout21_3, 'text');
            app.PinEditField.Value = '0';
            app.AbsoluteEditField_5Label = uilabel(app.GridLayout21_3);
            app.AbsoluteEditField_5Label.Text = 'Absolute';
            app.PhaseAbsoluteEditField = uieditfield(app.GridLayout21_3, 'text');
            app.PhaseAbsoluteEditField.Value = '0';
            app.FreqHzLabel = uilabel(app.GridLayout21_3);
            app.FreqHzLabel.Text = 'Freq. (Hz)';
            app.FrequencyEditField = uieditfield(app.GridLayout21_3, 'numeric');
            app.PHASEINDUCTIONMOTORLabel_2 = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel_2.Text = '3-PHASE INDUCTION MOTOR';
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.MotorSpeedPanel = uipanel(app.GridLayout5);
            app.MotorSpeedPanel.Title = 'Motor Speed';
            app.GridLayout10_2 = uigridlayout(app.MotorSpeedPanel);
            app.mfIndexLabel = uilabel(app.GridLayout10_2);
            app.mfIndexLabel.Text = 'mf Index';
            app.mfIndexEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.mfIndexEditField.ValueChangedFcn = createCallbackFcn(app, @mfIndexEditFieldValueChanged, true);
            app.SpeedrpmEditField = uieditfield(app.GridLayout10_2, 'numeric');
            app.SpeedrpmEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedrpmEditFieldValueChanged, true);
            app.RPMLabel = uilabel(app.GridLayout10_2);
            app.RPMLabel.Text = 'RPM';
            app.SpeedKnob = uiknob(app.GridLayout10_2, 'continuous');
            app.SpeedKnob.Limits = [0 3600];
            app.SpeedKnob.ValueChangingFcn = createCallbackFcn(app, @SpeedKnobValueChanging, true);
            app.RPMLabel_2 = uilabel(app.GridLayout10_2);
            app.RPMLabel_2.Text = 'RPM';
            app.ParametersOhmsPanel = uipanel(app.GridLayout5);
            app.ParametersOhmsPanel.Title = 'Parameters (Ohms)';
            app.GridLayout6 = uigridlayout(app.ParametersOhmsPanel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RsEditField.Editable = 'off';
            app.jXsEditFieldLabel = uilabel(app.GridLayout6);
            app.jXsEditFieldLabel.Text = 'jXs';
            app.jXsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXsEditField.Editable = 'off';
            app.jXmEditFieldLabel = uilabel(app.GridLayout6);
            app.jXmEditFieldLabel.Text = 'jXm';
            app.jXmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXmEditField.Editable = 'off';
            app.jXrEditFieldLabel = uilabel(app.GridLayout6);
            app.jXrEditFieldLabel.Text = 'jXr';
            app.jXrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.jXrEditField.Editable = 'off';
            app.RrEditFieldLabel = uilabel(app.GridLayout6);
            app.RrEditFieldLabel.Text = 'Rr''';
            app.RrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrEditField.Editable = 'off';
            app.MotorLoadPanel_2 = uipanel(app.GridLayout5);
            app.MotorLoadPanel_2.Title = 'Motor Load';
            app.GridLayout16 = uigridlayout(app.MotorLoadPanel_2);
            app.LoadTorqueLabel = uilabel(app.GridLayout16);
            app.LoadTorqueLabel.Text = 'Load Torque';
            app.LoadTorqueKnob = uiknob(app.GridLayout16, 'continuous');
            app.LoadTorqueKnob.ValueChangingFcn = createCallbackFcn(app, @LoadTorqueKnobValueChanging, true);
            app.TorqueNmLabel = uilabel(app.GridLayout16);
            app.TorqueNmLabel.Text = 'Torque (N*m)';
            app.LoadTorqueEditField = uieditfield(app.GridLayout16, 'numeric');
            app.LoadTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @LoadTorqueEditFieldValueChanged, true);
            app.StartButton = uibutton(app.GridLayout5, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DefaultParametersButton = uibutton(app.GridLayout5, 'push');
            app.DefaultParametersButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultParametersButtonPushed, true);
            app.DefaultParametersButton.Text = 'Default Parameters';
            app.EraseAllButton = uibutton(app.GridLayout5, 'push');
            app.EraseAllButton.ButtonPushedFcn = createCallbackFcn(app, @EraseAllButtonPushed, true);
            app.EraseAllButton.Text = 'Erase All';
            app.SetParametersMessageBox = uieditfield(app.GridLayout5, 'text');
            app.SetParametersMessageBox.Editable = 'off';
            app.SetParametersMessageBox.Visible = 'off';
            app.HelpButton = uibutton(app.GridLayout5, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Panel_2 = uipanel(app.GridLayout5);
            app.GridLayout19 = uigridlayout(app.Panel_2);
            app.FuseLampLabel = uilabel(app.GridLayout19);
            app.FuseLampLabel.Text = 'Fuse';
            app.FuseLamp = uilamp(app.GridLayout19);
            app.TotalImpedancePanel_2 = uipanel(app.GridLayout5);
            app.TotalImpedancePanel_2.Title = 'Total Impedance';
            app.GridLayout21_4 = uigridlayout(app.TotalImpedancePanel_2);
            app.AbsoluteEditField_6Label = uilabel(app.GridLayout21_4);
            app.AbsoluteEditField_6Label.Text = 'Absolute';
            app.ImpedanceAbsolute = uieditfield(app.GridLayout21_4, 'text');
            app.ImpedanceAbsolute.Value = '0';
            app.AngleEditField_2Label_2 = uilabel(app.GridLayout21_4);
            app.AngleEditField_2Label_2.Text = 'Angle';
            app.ImpedanceAngle = uieditfield(app.GridLayout21_4, 'text');
            app.ImpedanceAngle.Value = '0';
            app.ZEditField_2Label = uilabel(app.GridLayout21_4);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout21_4, 'text');
            app.ZEditField.Value = '0';
            app.XEditField = uieditfield(app.GridLayout21_4, 'text');
            app.XEditField.Value = '0';
            app.XEditField_2Label = uilabel(app.GridLayout21_4);
            app.XEditField_2Label.Text = 'X';
            app.REditField_2Label = uilabel(app.GridLayout21_4);
            app.REditField_2Label.Text = 'R';
            app.REditField = uieditfield(app.GridLayout21_4, 'text');
            app.REditField.Value = '0';
            app.PerformancePanel_2 = uipanel(app.GridLayout5);
            app.PerformancePanel_2.Title = 'Performance';
            app.GridLayout21_8 = uigridlayout(app.PerformancePanel_2);
            app.PFEditField_2Label = uilabel(app.GridLayout21_8);
            app.PFEditField_2Label.Text = 'PF';
            app.PFEditField = uieditfield(app.GridLayout21_8, 'text');
            app.PFEditField.Value = '0';
            app.EfficiencyEditFieldLabel = uilabel(app.GridLayout21_8);
            app.EfficiencyEditFieldLabel.Text = 'Efficiency';
            app.EfficiencyEditField = uieditfield(app.GridLayout21_8, 'text');
            app.EfficiencyEditField.Value = '0';
            app.SlipLabel = uilabel(app.GridLayout21_8);
            app.SlipLabel.Text = 'Slip (%)';
            app.SlipEditField = uieditfield(app.GridLayout21_8, 'text');
            app.SlipEditField.Value = '0';
            app.PoutLabel = uilabel(app.GridLayout21_8);
            app.PoutLabel.Text = 'Pout';
            app.PoutEditField = uieditfield(app.GridLayout21_8, 'text');
            app.PoutEditField.Value = '0';
            app.TorqueEditFieldLabel = uilabel(app.GridLayout21_8);
            app.TorqueEditFieldLabel.Text = 'Torque';
            app.TorqueEditField = uieditfield(app.GridLayout21_8, 'text');
            app.TorqueEditField.Value = '0';
            app.PIDPanel = uipanel(app.GridLayout5);
            app.PIDPanel.Title = 'PID';
            app.GridLayout20 = uigridlayout(app.PIDPanel);
            app.PEditFieldLabel = uilabel(app.GridLayout20);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.GridLayout20, 'text');
            app.PEditField.Value = '0';
            app.IEditFieldLabel = uilabel(app.GridLayout20);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.GridLayout20, 'text');
            app.IEditField.Value = '0';
            app.DEditFieldLabel = uilabel(app.GridLayout20);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.GridLayout20, 'text');
            app.DEditField.Value = '0';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GridLayout4 = uigridlayout(app.ResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'-Select-', 'Data (Excel)', 'Data (txt)'};
            app.ExportDropDown.ValueChangedFcn = createCallbackFcn(app, @ExportDropDownValueChanged, true);
            app.ExportDropDown.Value = '-Select-';
            app.TabGroup2 = uitabgroup(app.GridLayout4);
            app.GeneralResultsTab = uitab(app.TabGroup2);
            app.GeneralResultsTab.Title = 'General Results';
            app.GridLayout17 = uigridlayout(app.GeneralResultsTab);
            app.GraphGeneralVoltage = uiaxes(app.GridLayout17);
            title(app.GraphGeneralVoltage, 'Voltage')
            xlabel(app.GraphGeneralVoltage, 'T(s)')
            ylabel(app.GraphGeneralVoltage, 'V')
            app.GraphGeneralCurrent = uiaxes(app.GridLayout17);
            title(app.GraphGeneralCurrent, 'Current')
            xlabel(app.GraphGeneralCurrent, 'T(s)')
            ylabel(app.GraphGeneralCurrent, 'A')
            app.GraphGeneralSpeed = uiaxes(app.GridLayout17);
            title(app.GraphGeneralSpeed, 'Speed')
            xlabel(app.GraphGeneralSpeed, 'T(s)')
            ylabel(app.GraphGeneralSpeed, 'RPM')
            app.GraphGeneralTorque = uiaxes(app.GridLayout17);
            title(app.GraphGeneralTorque, 'Torque')
            xlabel(app.GraphGeneralTorque, 'T(s)')
            ylabel(app.GraphGeneralTorque, 'N.m')
            app.ElectroMagneticTorqueTab = uitab(app.TabGroup2);
            app.ElectroMagneticTorqueTab.Title = 'Electro-Magnetic Torque';
            app.GridLayout13 = uigridlayout(app.ElectroMagneticTorqueTab);
            app.GraphTabTorque = uiaxes(app.GridLayout13);
            title(app.GraphTabTorque, '')
            xlabel(app.GraphTabTorque, 'T(s)')
            ylabel(app.GraphTabTorque, 'N.m')
            app.SpeedTab = uitab(app.TabGroup2);
            app.SpeedTab.Title = 'Speed ';
            app.GridLayout14 = uigridlayout(app.SpeedTab);
            app.GraphTabSpeed = uiaxes(app.GridLayout14);
            title(app.GraphTabSpeed, '')
            xlabel(app.GraphTabSpeed, 'T(s)')
            ylabel(app.GraphTabSpeed, 'rpm')
            app.Current = uitab(app.TabGroup2);
            app.Current.Title = 'Current';
            app.GridLayout15 = uigridlayout(app.Current);
            app.GraphTabCurrent = uiaxes(app.GridLayout15);
            title(app.GraphTabCurrent, '')
            xlabel(app.GraphTabCurrent, 'T(s)')
            ylabel(app.GraphTabCurrent, 'A')
            zlabel(app.GraphTabCurrent, 'Z')
            app.VoltageTab = uitab(app.TabGroup2);
            app.VoltageTab.Title = 'Voltage';
            app.GridLayout18 = uigridlayout(app.VoltageTab);
            app.GraphTabVoltage = uiaxes(app.GridLayout18);
            title(app.GraphTabVoltage, '')
            xlabel(app.GraphTabVoltage, 'T(s)')
            ylabel(app.GraphTabVoltage, 'V')
            zlabel(app.GraphTabVoltage, 'Z')
            app.RefreshButton = uibutton(app.GridLayout4, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.TimeStopSliderLabel = uilabel(app.GridLayout4);
            app.TimeStopSliderLabel.Text = {'Time Stop'; ''};
            app.TimeStopSlider = uislider(app.GridLayout4);
            app.TimeStopSlider.Limits = [0 15];
            app.TimeStopSlider.Value = 2;
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.VFLaboratoryClosedLoopUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VbyFClosedLoop(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VFLaboratoryClosedLoopUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VFLaboratoryClosedLoopUIFigure)
        end
    end
end